classdef Project3_Q1_RezaRoodi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label                       matlab.ui.control.Label
        RezaRoodiLabel              matlab.ui.control.Label
        CoordDropDown               matlab.ui.control.DropDown
        CoordinateDropDownLabel     matlab.ui.control.Label
        FunctionEditField           matlab.ui.control.EditField
        FunctionfEditFieldLabel_7   matlab.ui.control.Label
        Upper3                      matlab.ui.control.EditField
        Upper3Label                 matlab.ui.control.Label
        Upper1                      matlab.ui.control.EditField
        Upper1Label                 matlab.ui.control.Label
        Lower1                      matlab.ui.control.EditField
        Lower1Label                 matlab.ui.control.Label
        Lower3                      matlab.ui.control.EditField
        Lower3Label                 matlab.ui.control.Label
        Upper2                      matlab.ui.control.EditField
        Upper2Label                 matlab.ui.control.Label
        Lower2                      matlab.ui.control.EditField
        Lower2Label                 matlab.ui.control.Label
        OrderDropDown               matlab.ui.control.DropDown
        IntegralOrderDropDownLabel  matlab.ui.control.Label
        ResultLabel                 matlab.ui.control.Label
        MethodButtonGroup           matlab.ui.container.ButtonGroup
        NumericalButton             matlab.ui.control.ToggleButton
        AnalyticalButton            matlab.ui.control.ToggleButton
        CalcButton                  matlab.ui.control.Button
        TypeButtonGroup             matlab.ui.container.ButtonGroup
        DefiniteButton              matlab.ui.control.ToggleButton
        IndefiniteButton            matlab.ui.control.ToggleButton
        FormulaLabel                matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    methods (Access = private)
        function updateVisibility(app)
            orderVal = app.OrderDropDown.Value;
            typeVal = app.TypeButtonGroup.SelectedObject.Text;
          
            app.Lower1.Visible = 'off'; app.Upper1.Visible = 'off';
            app.Lower2.Visible = 'off'; app.Upper2.Visible = 'off';
            app.Lower3.Visible = 'off'; app.Upper3.Visible = 'off';
            
            if strcmp(typeVal, 'Definite')
                app.Lower1.Visible = 'on'; app.Upper1.Visible = 'on';
                
                if strcmp(orderVal, '2') || strcmp(orderVal, '3')
                    app.Lower2.Visible = 'on'; app.Upper2.Visible = 'on';
                end
                
                if strcmp(orderVal, '3')
                    app.Lower3.Visible = 'on'; app.Upper3.Visible = 'on';
                end
            end
        end
    
        function updateFormula(app)
            orderVal = app.OrderDropDown.Value;
            typeVal = app.TypeButtonGroup.SelectedObject.Text;
            coordVal = app.CoordDropDown.Value;
            
            app.FormulaLabel.Interpreter = 'latex';
            
            if strcmp(coordVal, 'Cartesian')
                if strcmp(typeVal, 'Indefinite')
                    switch orderVal
                        case '1', app.FormulaLabel.Text = '$$\int f(x) dx$$';
                        case '2', app.FormulaLabel.Text = '$$\int\int f(x,y) dx dy$$';
                        case '3', app.FormulaLabel.Text = '$$\int\int\int f(x,y,z) dx dy dz$$';
                    end
                else
                    switch orderVal
                        case '1', app.FormulaLabel.Text = '$$\int_{a}^{b} f(x) dx$$';
                        case '2', app.FormulaLabel.Text = '$$\int_{c}^{d} \int_{g_1(y)}^{g_2(y)} f(x,y) dx dy$$';
                        case '3', app.FormulaLabel.Text = '$$\int_{m}^{n} \int_{g_1(z)}^{g_2(z)} \int_{h_1(y,z)}^{h_2(y,z)} f(x,y,z) dx dy dz$$';
                    end
                end
                
            elseif strcmp(coordVal, 'Cylindrical')
                % --- مختصات استوانه‌ای (r, theta, z) ---
                if strcmp(typeVal, 'Indefinite')
                    switch orderVal
                        case '1', app.FormulaLabel.Text = '$$\int f(r) dr$$';
                        case '2', app.FormulaLabel.Text = '$$\int\int f(r,\theta) r \, dr \, d\theta$$';
                        case '3', app.FormulaLabel.Text = '$$\int\int\int f(r,\theta,z) r \, dr \, d\theta \, dz$$';
                    end
                else
                    switch orderVal
                        case '1', app.FormulaLabel.Text = '$$\int_{r_1}^{r_2} f(r) dr$$';
                        case '2', app.FormulaLabel.Text = '$$\int_{\theta_1}^{\theta_2} \int_{r_1}^{r_2} f(r,\theta) r \, dr \, d\theta$$';
                        case '3', app.FormulaLabel.Text = '$$\int_{z_1}^{z_2} \int_{\theta_1}^{\theta_2} \int_{r_1}^{r_2} f(r,\theta,z) r \, dr \, d\theta \, dz$$';
                    end
                end
                
            elseif strcmp(coordVal, 'Spherical')
                if strcmp(typeVal, 'Indefinite')
                    switch orderVal
                        case '1', app.FormulaLabel.Text = '$$\int f(\rho) d\rho$$';
                        case '2', app.FormulaLabel.Text = '$$\int\int f(\rho,\phi) \rho^2 \sin(\phi) d\rho \, d\phi$$';
                        case '3', app.FormulaLabel.Text = '$$\int\int\int f(\rho,\phi,\theta) \rho^2 \sin(\phi) d\rho \, d\phi \, d\theta$$';
                    end
                else
                    switch orderVal
                        case '1', app.FormulaLabel.Text = '$$\int_{\rho_1}^{\rho_2} f(\rho) d\rho$$';
                        case '2', app.FormulaLabel.Text = '$$\int_{\phi_1}^{\phi_2} \int_{\rho_1}^{\rho_2} f(\rho,\phi) \rho^2 \sin(\phi) d\rho \, d\phi$$';
                        case '3', app.FormulaLabel.Text = '$$\int_{\theta_1}^{\theta_2} \int_{\phi_1}^{\phi_2} \int_{\rho_1}^{\rho_2} f(\rho,\phi,\theta) \rho^2 \sin(\phi) d\rho \, d\phi \, d\theta$$';
                    end
                end
            end
        end
        function tf = IsValidBound(app, val)
            tf = isnumeric(val) && isscalar(val) && ~isnan(val) && ~isinf(val);
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
updateFormula(app);
updateVisibility(app);
        end

        % Value changed function: OrderDropDown
        function OrderDropDownValueChanged(app, event)
            updateFormula(app);
            updateVisibility(app);
        end

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)
           updateFormula(app);
           updateVisibility(app);
        end

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
           funcStr = app.FunctionEditField.Value; 
        orderVal = app.OrderDropDown.Value;   
        typeVal = app.TypeButtonGroup.SelectedObject.Text; 
        methodVal = app.MethodButtonGroup.SelectedObject.Text; 
        coordVal = app.CoordDropDown.Value;   

        if isempty(funcStr)
            app.ResultLabel.Interpreter = 'text';
            app.ResultLabel.Text = 'لطفاً ضابطه تابع f را وارد کنید.';
            return;
        end

        if strcmp(typeVal, 'Definite')
            if isempty(app.Lower1.Value) || isempty(app.Upper1.Value) || ...
               (strcmp(orderVal, '2') && (isempty(app.Lower2.Value) || isempty(app.Upper2.Value))) || ...
               (strcmp(orderVal, '3') && (isempty(app.Lower3.Value) || isempty(app.Upper3.Value)))
                
                app.ResultLabel.Interpreter = 'text';
                app.ResultLabel.Text = 'خطا: لطفاً تمامی کران‌های انتگرال معین را وارد کنید.';
                return;
            end
        end
        
        try
            syms x y z r theta phi rho
            f = str2sym(funcStr); 
    
            if strcmp(coordVal, 'Cartesian')
                v1 = x; v2 = y; v3 = z;
            elseif strcmp(coordVal, 'Cylindrical')
                v1 = r; v2 = theta; v3 = z;
            elseif strcmp(coordVal, 'Spherical')
                v1 = rho; v2 = phi; v3 = theta;
            end
            
            if strcmp(coordVal, 'Cylindrical')
                if strcmp(orderVal, '2') || strcmp(orderVal, '3')
                    f = f * r; 
                end
            elseif strcmp(coordVal, 'Spherical')
                if strcmp(orderVal, '2') || strcmp(orderVal, '3')
                    f = f * (rho^2 * sin(phi)); 
                end
            end
            
            if strcmp(typeVal, 'Indefinite')
                if strcmp(methodVal, 'Numerical')
                    app.ResultLabel.Interpreter = 'text';
                    app.ResultLabel.Text = 'خطا: انتگرال نامعین حل عددی ندارد!';
                    return;
                end
                
                switch orderVal
                    case '1', res = int(f, v1);
                    case '2', res = int(int(f, v1), v2);
                    case '3', res = int(int(int(f, v1), v2), v3);
                end
                
                app.ResultLabel.Interpreter = 'latex';
                app.ResultLabel.Text = ['$$' latex(res) ' + C$$'];
                
            else
                switch orderVal
                    case '1'
                        a1 = str2sym(app.Lower1.Value); b1 = str2sym(app.Upper1.Value);
                        res = int(f, v1, a1, b1);
                        
                    case '2'
                        a1 = str2sym(app.Lower1.Value); b1 = str2sym(app.Upper1.Value);
                        a2 = str2sym(app.Lower2.Value); b2 = str2sym(app.Upper2.Value);
                        res = int(int(f, v1, a1, b1), v2, a2, b2);
                        
                    case '3'
                        a1 = str2sym(app.Lower1.Value); b1 = str2sym(app.Upper1.Value);
                        a2 = str2sym(app.Lower2.Value); b2 = str2sym(app.Upper2.Value);
                        a3 = str2sym(app.Lower3.Value); b3 = str2sym(app.Upper3.Value);
                        res = int(int(int(f, v1, a1, b1), v2, a2, b2), v3, a3, b3);
                end
                
                app.ResultLabel.Interpreter = 'latex';
                if strcmp(methodVal, 'Analytical')
                    app.ResultLabel.Text = ['$$' latex(res) '$$'];
                else
                    res_num = vpaintegral(f, v1, a1, b1);
                    app.ResultLabel.Text = ['$$' latex(res_num) '$$'];
                end
            end
            
            cla(app.UIAxes);
            try
                f_sym = str2sym(app.FunctionEditField.Value);
                
                if strcmp(typeVal, 'Indefinite')
                    plot_target = res; 
                    titleStr = 'Integral Result Graph';
                else
                    plot_target = f_sym;
                    titleStr = 'Original Function Graph';
                end
                
                xlabel(app.UIAxes, char(v1));
                if strcmp(orderVal, '1')
                    ylabel(app.UIAxes, 'Output');
                else
                    ylabel(app.UIAxes, char(v2));
                end
                
                bnds1 = [-5, 5];
                bnds2 = [-5, 5];
                
                if strcmp(typeVal, 'Definite')
                    try
                        a1_num = double(str2sym(app.Lower1.Value));
                        b1_num = double(str2sym(app.Upper1.Value));
                        if app.IsValidBound(a1_num) && app.IsValidBound(b1_num)
                            bnds1 = [a1_num, b1_num];
                        end
                    catch
                    end
                    
                    try
                        if strcmp(orderVal, '2')
                            a2_num = double(str2sym(app.Lower2.Value));
                            b2_num = double(str2sym(app.Upper2.Value));
                            if app.IsValidBound(a2_num) && app.IsValidBound(b2_num)
                                bnds2 = [a2_num, b2_num];
                            end
                        end
                    catch
                    end
                end
                
                if strcmp(orderVal, '1')
                    fplot(app.UIAxes, plot_target, v1, bnds1); 
                    title(app.UIAxes, [titleStr ' (1D)']);
                elseif strcmp(orderVal, '2')
                    fsurf(app.UIAxes, plot_target, [bnds1(1), bnds1(2), bnds2(1), bnds2(2)]);
                    title(app.UIAxes, [titleStr ' (2D)']);
                end
                
            catch
            end
            
        catch ME
            app.ResultLabel.Interpreter = 'text';
            app.ResultLabel.Text = ['خطا در محاسبات: ' ME.message];
        end

        end

        % Value changed function: CoordDropDown
        function CoordDropDownValueChanged(app, event)
            value = app.CoordDropDown.Value;
            updateFormula(app);
            updateVisibility(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'diagram')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FormulaLabel = uilabel(app.UIFigure);
            app.FormulaLabel.Interpreter = 'latex';
            app.FormulaLabel.Text = '';
            app.TypeButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.TypeButtonGroup.Title = 'Integral Type';
            app.IndefiniteButton = uitogglebutton(app.TypeButtonGroup);
            app.IndefiniteButton.Text = 'Indefinite';
            app.IndefiniteButton.Value = true;
            app.DefiniteButton = uitogglebutton(app.TypeButtonGroup);
            app.DefiniteButton.Text = 'Definite';
            app.CalcButton = uibutton(app.UIFigure, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'Calculate';
            app.MethodButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodButtonGroup.Title = 'Solution Method';
            app.AnalyticalButton = uitogglebutton(app.MethodButtonGroup);
            app.AnalyticalButton.Text = 'Analytical';
            app.AnalyticalButton.Value = true;
            app.NumericalButton = uitogglebutton(app.MethodButtonGroup);
            app.NumericalButton.Text = 'Numerical';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Interpreter = 'latex';
            app.ResultLabel.Text = 'result';
            app.IntegralOrderDropDownLabel = uilabel(app.UIFigure);
            app.IntegralOrderDropDownLabel.Text = 'Integral Order';
            app.OrderDropDown = uidropdown(app.UIFigure);
            app.OrderDropDown.Items = {'1', '2', '3'};
            app.OrderDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderDropDownValueChanged, true);
            app.OrderDropDown.Value = '1';
            app.Lower2Label = uilabel(app.UIFigure);
            app.Lower2Label.Text = 'Lower 2';
            app.Lower2 = uieditfield(app.UIFigure, 'text');
            app.Upper2Label = uilabel(app.UIFigure);
            app.Upper2Label.Text = 'Upper 2';
            app.Upper2 = uieditfield(app.UIFigure, 'text');
            app.Lower3Label = uilabel(app.UIFigure);
            app.Lower3Label.Text = 'Lower 3';
            app.Lower3 = uieditfield(app.UIFigure, 'text');
            app.Lower1Label = uilabel(app.UIFigure);
            app.Lower1Label.Text = 'Lower 1';
            app.Lower1 = uieditfield(app.UIFigure, 'text');
            app.Upper1Label = uilabel(app.UIFigure);
            app.Upper1Label.Text = 'Upper 1';
            app.Upper1 = uieditfield(app.UIFigure, 'text');
            app.Upper3Label = uilabel(app.UIFigure);
            app.Upper3Label.Text = 'Upper 3';
            app.Upper3 = uieditfield(app.UIFigure, 'text');
            app.FunctionfEditFieldLabel_7 = uilabel(app.UIFigure);
            app.FunctionfEditFieldLabel_7.Text = 'Function f:';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.CoordinateDropDownLabel = uilabel(app.UIFigure);
            app.CoordinateDropDownLabel.Text = 'Coordinate';
            app.CoordDropDown = uidropdown(app.UIFigure);
            app.CoordDropDown.Items = {'Cartesian', 'Cylindrical', 'Spherical'};
            app.CoordDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordDropDownValueChanged, true);
            app.CoordDropDown.Value = 'Cartesian';
            app.RezaRoodiLabel = uilabel(app.UIFigure);
            app.RezaRoodiLabel.Text = 'Reza Roodi';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '4032202378';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project3_Q1_RezaRoodi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end