classdef Project3_Q2_RezaRoodi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ResultTextArea       matlab.ui.control.TextArea
        ResultTextAreaLabel  matlab.ui.control.Label
        SolveButton          matlab.ui.control.Button
        ICEditField          matlab.ui.control.EditField
        ICEditFieldLabel     matlab.ui.control.Label
        FxEditField          matlab.ui.control.EditField
        FxEditFieldLabel     matlab.ui.control.Label
        CoeffEditField       matlab.ui.control.EditField
        CoeffEditFieldLabel  matlab.ui.control.Label
        EquationLabel        matlab.ui.control.Label
        MethodDropDown       matlab.ui.control.DropDown
        MethodDropDownLabel  matlab.ui.control.Label
        OrderSpinner         matlab.ui.control.Spinner
        OrderSpinnerLabel    matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

methods (Access = private)
        function dY = stateSpace(app, x, Y, coeffs, f_func, n)
            dY = zeros(n, 1);
            for i = 1:n-1
                dY(i) = Y(i+1);
            end
            an = coeffs(1);
            sum_terms = 0;
            for i = 1:n
                sum_terms = sum_terms + coeffs(n - i + 2) * Y(i);
            end
            dY(n) = (f_func(x) - sum_terms) / an;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
n = app.OrderSpinner.Value;
eqStr = "";
for i = n:-1:1
    primes = repmat('''', 1, i);
    eqStr = eqStr + "a_" + num2str(i) + "y" + primes + " + ";
end
eqStr = eqStr + "a_0y = f(x)";
icStr = "y(0)=k_0";
for i = 1:n-1
    primes = repmat('''', 1, i);
    icStr = icStr + ", y" + primes + "(0)=k_" + num2str(i);
end
app.EquationLabel.Text = sprintf('%s\n%s', eqStr, icStr);
        end

        % Value changed function: OrderSpinner
        function OrderSpinnerValueChanged(app, event)
n = app.OrderSpinner.Value;
eqStr = "";
for i = n:-1:1
    primes = repmat('''', 1, i);
    eqStr = eqStr + "a_" + num2str(i) + "y" + primes + " + ";
end
eqStr = eqStr + "a_0y = f(x)";
icStr = "y(0)=k_0";
for i = 1:n-1
    primes = repmat('''', 1, i);
    icStr = icStr + ", y" + primes + "(0)=k_" + num2str(i);
end
app.EquationLabel.Text = sprintf('%s\n%s', eqStr, icStr);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
try
    n = app.OrderSpinner.Value;
    method = app.MethodDropDown.Value;
    coeffsStr = app.CoeffEditField.Value;
    coeffs = str2num(coeffsStr);
    fxStr = app.FxEditField.Value;
    icStr = app.ICEditField.Value;
    ics = str2num(icStr);
    
    if length(coeffs) ~= n + 1
        app.ResultTextArea.Value = "Error: Invalid coefficients count.";
        return;
    end
    
    if length(ics) ~= n
        app.ResultTextArea.Value = "Error: Invalid initial conditions count.";
        return;
    end
    
    if strcmp(method, 'Analytical')
        syms y(x)
        eqn = 0;
        for i = 0:n
            eqn = eqn + coeffs(n - i + 1) * diff(y, x, i);
        end
        f_sym = str2sym(fxStr);
        eqn = eqn == f_sym;
        conds = sym(zeros(1, n));
        for i = 1:n
            if i == 1
                conds(1) = y(0) == ics(1);
            else
                d_y = diff(y, x, i-1);
                conds(i) = d_y(0) == ics(i);
            end
        end
        sol = dsolve(eqn, conds);
        app.ResultTextArea.Value = char(sol);
        fplot(app.UIAxes, sol, [0, 10]);
        title(app.UIAxes, 'Analytical Response');
    else
        f_func = str2func(['@(x) ' fxStr]);
        odefun = @(x, Y) app.stateSpace(x, Y, coeffs, f_func, n);
        [X, Y_num] = ode45(odefun, [0, 10], ics);
        plot(app.UIAxes, X, Y_num(:,1));
        title(app.UIAxes, 'Numerical Response');
        app.ResultTextArea.Value = "Numerical solution plotted.";
    end
catch ME
    app.ResultTextArea.Value = "Error: " + ME.message;
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'UIAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OrderSpinnerLabel = uilabel(app.UIFigure);
            app.OrderSpinnerLabel.Text = 'OrderSpinner';
            app.OrderSpinner = uispinner(app.UIFigure);
            app.OrderSpinner.ValueChangedFcn = createCallbackFcn(app, @OrderSpinnerValueChanged, true);
            app.OrderSpinner.Value = 2;
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'MethodDropDown';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Analytical', 'Numerical'};
            app.MethodDropDown.Value = 'Analytical';
            app.EquationLabel = uilabel(app.UIFigure);
            app.EquationLabel.Text = 'EquationLabel';
            app.CoeffEditFieldLabel = uilabel(app.UIFigure);
            app.CoeffEditFieldLabel.Text = 'CoeffEditField';
            app.CoeffEditField = uieditfield(app.UIFigure, 'text');
            app.FxEditFieldLabel = uilabel(app.UIFigure);
            app.FxEditFieldLabel.Text = 'FxEditField';
            app.FxEditField = uieditfield(app.UIFigure, 'text');
            app.ICEditFieldLabel = uilabel(app.UIFigure);
            app.ICEditFieldLabel.Text = 'ICEditField';
            app.ICEditField = uieditfield(app.UIFigure, 'text');
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'SolveButton';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'ResultTextArea';
            app.ResultTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project3_Q2_RezaRoodi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end